classdef Diatomix < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout            matlab.ui.container.GridLayout
        GridLayout2           matlab.ui.container.GridLayout
        GridLayout3           matlab.ui.container.GridLayout
        ExportButton          matlab.ui.control.Button
        statusLabel           matlab.ui.control.Label
        statusLamp            matlab.ui.control.Lamp
        Label                 matlab.ui.control.Label
        AutoUpdateButton      matlab.ui.control.StateButton
        CalculateButton       matlab.ui.control.Button
        TabGroup              matlab.ui.container.TabGroup
        FieldsTab             matlab.ui.container.Tab
        GridLayout4           matlab.ui.container.GridLayout
        PolarisationLabel     matlab.ui.control.Label
        DirectionLabel_2      matlab.ui.control.Label
        FieldAmplitudeLabel   matlab.ui.control.Label
        Laser1PolField        matlab.ui.control.EditField
        BLabel                matlab.ui.control.Label
        BField                matlab.ui.control.EditField
        BDirField             matlab.ui.control.EditField
        Laser1PolPhaseField   matlab.ui.control.EditField
        aEditFieldLabel       matlab.ui.control.Label
        Laser1PolAngleField   matlab.ui.control.EditField
        degreeLabel           matlab.ui.control.Label
        ILabel                matlab.ui.control.Label
        ELabel                matlab.ui.control.Label
        Laser1DirField        matlab.ui.control.EditField
        EDirField             matlab.ui.control.EditField
        EField                matlab.ui.control.EditField
        Laser1Field           matlab.ui.control.EditField
        MoleculeTab           matlab.ui.container.Tab
        GridLayout7           matlab.ui.container.GridLayout
        MoleculeEditField     matlab.ui.control.EditField
        MoleculeLabel         matlab.ui.control.Label
        Tree                  matlab.ui.container.CheckBoxTree
        RigidRotorNode        matlab.ui.container.TreeNode
        SpinSpinScalarNode    matlab.ui.container.TreeNode
        SpinSpinTensorNode    matlab.ui.container.TreeNode
        SpinRotationNode      matlab.ui.container.TreeNode
        NuclearElectricNode   matlab.ui.container.TreeNode
        BasisTab              matlab.ui.container.Tab
        GridLayout6           matlab.ui.container.GridLayout
        BasisDropDown         matlab.ui.control.DropDown
        BasisDropDownLabel    matlab.ui.control.Label
        NmaxEditField         matlab.ui.control.NumericEditField
        NmaxEditFieldLabel    matlab.ui.control.Label
        TextArea_3            matlab.ui.control.TextArea
        PlotTab               matlab.ui.container.Tab
        GridLayout5           matlab.ui.container.GridLayout
        N2EditField           matlab.ui.control.EditField
        N2EditFieldLabel      matlab.ui.control.Label
        N1EditField           matlab.ui.control.EditField
        N1EditFieldLabel      matlab.ui.control.Label
        Y2DropDown            matlab.ui.control.DropDown
        Y2DropDownLabel       matlab.ui.control.Label
        Y1DropDown            matlab.ui.control.DropDown
        Y1DropDownLabel       matlab.ui.control.Label
        ShowPointsCheckBox    matlab.ui.control.CheckBox
        PlotAdiabatsCheckBox  matlab.ui.control.CheckBox
        UITable               matlab.ui.control.Table
        UIAxes                matlab.ui.control.UIAxes
    end

    properties (Access = private)
        Fields % Container for all fields
        Ham % Hamiltonian
        Molecule
        UCBasis % Uncoupled Basis
    end
    
    methods (Access = private)

        function updatePlot(comp)
            setStatus(comp, 'yellow', 'Updating Plot');
            ud = comp.UserData;
            ax = comp.UIAxes;

            delete(findobj(ax,'Type','line'))
            delete(findobj(ax,'Type','ConstantLine'))
            delete(findobj(ax,'Type','Scatter'))
            delete(findobj(ax,'Tag','update'))
            % spec = ud.spectrum(:,ud.selectStates);
            y1 = ud.yVar1.value(:,ud.selectStates);
            %Part for the first axis
            % yyaxis(ax,'left')
            % ax.YAxis(1).Color = "k";
            switch string(ud.yVar1.name)
                case "E"
                    set(ax, 'YDir','normal')
                case "E-<E>"
                    y1 = y1 - mean(y1,2);
                    set(ax, 'YDir','normal')
                case "µ"
                    set(ax, 'YDir','reverse')
                case "d"
                    set(ax, 'YDir','normal')
            end
            if ~isempty(y1)
                ps1 = plot(ax,ud.xVar.value,y1,'-', "Color",[1,1,1]*0.15, LineWidth=1);
                if comp.ShowPointsCheckBox.Value
                    hold(ax,'on');
                    plot(ax,ud.xVar.value,y1, ...
                        'o',MarkerSize=2, MarkerFaceColor=[1,1,1]*0.1, MarkerEdgeColor="none");
                    hold(ax,'off');
                end
            end
            % for p=1:length(ps)
            %     ps(p).DataTipTemplate(1).DataTipRows(2).Label="E(MHz)=";
            % %     ps(p).DataTipTemplate(1).DataTipRows(3).Label="E(MHz)=";
            % end
            xlabel(ax,sprintf("%s (%s)",ud.xVar.name,ud.xVar.unit))
            ylabel(ax,sprintf("%s (%s)",ud.yVar1.name,ud.yVar1.unit))
            title(ax,comp.Molecule.name, Interpreter="tex")
            xlim(ax,[min(ud.xVar.value),max(ud.xVar.value)]);
            ylim(ax,'auto')
            legend(ax,'off');

            %Part for the second axis -- Not functional yet!
            % switch string(ud.currY2)
            %     case "E (MHz)"
            %         y2 = spec;
            %     case "E - <E> (MHz)"
            %         y2 = spec - mean(spec,2);
            %     case "µ"
            %         if isempty(ud.muEffective)
            %             mu = calculateMuEffective(comp);
            %             ud.muEffective = mu;
            %         end
            %         y2 = ud.muEffective(:,ud.selectStates);
            %         % y2=[];
            %     case "None"
            %         y2 = [];
            %         yyaxis(ax,'right')
            %         ax.YAxis(2).Visible='off';
            %         set(ax, 'YDir','reverse')
            % end
            % yyaxis(ax,'right')
            % if ~isempty(y2)
            %     ax.YAxis(2).Visible='on';
            %     ps2 = plot(ax,ud.xVar.value,y2, ...
            %     'o-', "Color","r",MarkerSize=2, LineWidth=1, MarkerFaceColor=[1,0,0], MarkerEdgeColor="none", Tag="Update");
            % end
            % xlim(ax,[min(ud.xVar.value),max(ud.xVar.value)]);
            % ylabel(ax,string(comp.Y2DropDown.Value))
            % 
            % ax.YAxis(1).Visible='on'; %Set focus back to left axis
            comp.UserData = ud;
            setStatus(comp, 'green', '');
        end
        function [diabat, diabatStates, adiabat, adiabatStates] = calcAdiabat(comp, xIdx,yIdx)
            ud = comp.UserData;
            y = ud.spectrum;
            states = ud.currStates;
            x = ud.xVar.value;            
            [diabat, diabatStates, adiabat, adiabatStates] = findAdiabat(x,y,states, xIdx,yIdx);
        end
        function calculateSpectrum(comp)
            C = Constants;
            setStatus(comp, 'yellow', 'Calculating');
            ud = comp.UserData;
            F = comp.Fields;
            h = comp.Ham;
            nStates = h.Basis.NStates;
            energies = nan(length(ud.xVar.value),nStates);
            states = nan(length(ud.xVar.value),nStates,nStates);
            % E.dir = F.E.dir;
            % B.dir = F.B.dir;
            E = F.E.value(1)*F.E.scale;
            B = F.B.value(1)*F.B.scale;
            I = F.I.value(1)*F.I.scale;
            tmp = namedargs2cell(comp.UserData.hamOpts); %because eugh, matlab...
            H0 = h.makeHyperfine(tmp{:});
            for k = 1:length(ud.xVar.value)
                if strcmp(ud.xVar.name,"B")
                    B = F.B.value(k)*F.B.scale;
                    H = H0 + h.zeeman*B + h.dc_stark*E + h.ac_stark*I;
                elseif strcmp(ud.xVar.name,"E")
                    E = F.E.value(k)*F.E.scale;
                    H = H0 + h.zeeman*B + h.dc_stark*E + h.ac_stark*I;
                elseif strcmp(ud.xVar.name,"I")
                    I = F.I.value(k)*F.I.scale;
                    H = H0 + h.zeeman*B + h.dc_stark*E + h.ac_stark*I;
                else
                end
                [V,D] = eig(full(H/C.h),'vector'); %no need to use sparse matrices for matrices smaller than 1000x1000. Divide by Plancks constant to get energy in Hz
                [d,sortIdx] = sort(real(D));
                energies(k,:) = d;        
                states(k,:,:) = V(:,sortIdx);
            end
            ud.currStates = states;
            ud.spectrum = energies*1e-6; %energies in MHz (xVar,States)

            ud.diabatStates=[];
            ud.muEffective = [];
            ud.inducedDipole = [];
            comp.UserData = ud;
            update(comp);
            setStatus(comp, 'green', '');
        end
        function updateHamiltonian(comp)
            ud = comp.UserData;
        
            Ham = Hamiltonian(Molecule = comp.Molecule, Fields=comp.Fields, maxN=ud.Nmax);
            comp.Ham = Ham; 

            UCBasis = Ham.Basis;
            statesUCBasis = UCBasis.getStates('all');
            stateNames = ["N","mN","mi1","mi2"];
            ud.statesUCBasis = statesUCBasis(:,stateNames);
            comp.UITable.ColumnName = ["Comp",stateNames];
            ud.selectStates = ismember(ud.statesUCBasis.N,ud.selectN);
            
            ud.diabatStates=[];
            ud.muEffective = [];
            ud.inducedDipole = [];
            comp.UserData = ud;
        end
        function sortStates(comp)
            % sortStates(comp) calculates all adiabats and returns sorted
            % states
            ud = comp.UserData;
            if isempty(ud.diabatStates) %only do it if it hasnt been done before for this hamiltonian
                x = ud.xVar.value;
                diabatStates = nan(size(ud.currStates));
                for stIdx = 1:size(diabatStates,3)
                    [~, diabatStates,~, ~ ] = calcAdiabat(comp, length(x),stIdx); %start from last state
                    diabatStates(:,:,stIdx) = diabatStates;
                end
                ud.diabatStates = diabatStates;
            end
            comp.UserData = ud;
        end
        function mu = calculateMuEffective(comp)
            setStatus(comp, 'yellow', 'Calculating');
            sortStates(comp); %first sort states as adiabats

            ud = comp.UserData;
            Fields = comp.Fields;
            C = Constants;
            Hz = comp.Ham.zeeman;
            mu = nan(size(ud.spectrum));
            psis = ud.diabatStates;
            for x = 1:length(ud.xVar.value)
                psi = squeeze(psis(x,:,:));
                mu(x,:) = diag(conj(psi')*Hz*psi); %expectation value of zeeman part of the Hamiltonian
            end
            ud.muEffective = -real(mu)/C.muN;
            comp.UserData = ud;
        end
        function d = calculateInducedDipole(comp)
            setStatus(comp, 'yellow', 'Calculating');
            sortStates(comp); %first sort states as adiabats
            
            ud = comp.UserData;
            Fields = comp.Fields;
            C = Constants;

            H_stark = comp.Ham.dc_stark;
            d = nan(size(ud.spectrum));
            psis = ud.diabatStates;
            for x = 1:length(ud.xVar.value)
                psi = squeeze(psis(x,:,:));
                d(x,:) = diag(conj(psi')*H_stark*psi); %expectation value of Stark part of the Hamiltonian
            end
            ud.inducedDipole = real(d)/C.D;
            comp.UserData = ud;
        end

        function updateY(comp)
           ud = comp.UserData;
           Fields = comp.Fields;
           C=Constants;
           x = ud.xVar.value * ud.xVar.scale;
           switch string(comp.Y1DropDown.Value)
                case "E"
                    yVar1.name = "E";
                    yVar1.unit = "MHz";
                    yVar1.value = comp.UserData.spectrum;
                case "E - <E>"
                    yVar1.name = "E - <E>";
                    yVar1.unit = "MHz";
                    y1 = comp.UserData.spectrum;
                    yVar1.value = y1 - mean(comp.UserData.spectrum(:,ud.selectStates),2);
               case "E - a0"
                    yVar1.name = "E - 2Ia_0/(\epsilon_0c)";
                    yVar1.unit = "MHz";
                    y1 = comp.UserData.spectrum;
                    yVar1.value = y1 + x'*comp.Molecule.a0/(2*C.h*C.e0*C.c)*1e-6;
                case "µ"
                    yVar1.name = "µ";
                    yVar1.unit = "µ_N";
                    if isempty(comp.UserData.muEffective)
                        calculateMuEffective(comp);
                    end
                    yVar1.value = comp.UserData.muEffective;
                    comp.UserData.currStates = comp.UserData.diabatStates;
                case "d"
                    yVar1.name = "d";
                    yVar1.unit = "d_0";
                    if isempty(comp.UserData.inducedDipole)
                        calculateInducedDipole(comp);
                    end
                    yVar1.value = comp.UserData.inducedDipole;
                    comp.UserData.currStates = comp.UserData.diabatStates;
           end
           comp.UserData.yVar1 = yVar1;
        end

        function bool = checkZoomed(comp)
            ax = comp.UIAxes;
            ud = comp.UserData;
            if any(ax.XLim~=ud.currPlotXlim)&&any(ax.YLim~=ud.currPlotYlim)
                ud.currPlotXlim = ax.XLim;
                ud.currPlotYlim = ax.YLim;
                bool = 1;
                comp.UserData = ud;
            else %didnt change
                bool = 0;
            end
        end
    end

    methods (Access = public)
        function setStatus(comp, color, message)
            comp.statusLamp.Color = color;
            comp.statusLabel.Text = message;
            pause(0.001);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function initialise(comp)
                % fig = comp.Parent;
                addpath("Atoms\")
                addpath("Molecules\")
                
                comp.UIAxes.InteractionOptions.BrushSupported = 'off';
                comp.UIAxes.InteractionOptions.RotateSupported = 'off';
                comp.UIAxes.InteractionOptions.PanLimitsBounded = 'on';

                ud = comp.UserData;
                ud.autoUpdate = 1;
                Fields = comp.Fields;
                Fields.B.name = "B"; Fields.B.unit = "G"; Fields.B.scale = 1e-4; %from Tesla to Gauss
                Fields.E.name = "E"; Fields.E.unit = "V/cm"; Fields.E.scale = 1e2; %from V/m to V/cm
                Fields.I.name = "I"; Fields.I.unit = "kW/cm^{2}"; Fields.I.scale = 1e7;
                Fields.B.dir = eval(comp.BDirField.Value);
                Fields.E.dir = eval(comp.EDirField.Value);
                Fields.I.dir = eval(comp.Laser1DirField.Value);
                Fields.I.polAngle = eval(comp.Laser1PolAngleField.Value);
                Fields.I.polPhase= eval(comp.Laser1PolPhaseField.Value);
                a = Fields.I.polPhase; b = Fields.I.polAngle;
                Fields.I.pol = eval(comp.Laser1PolField.Value);
                Fields.B.value = eval(comp.BField.Value);
                Fields.E.value = eval(comp.EField.Value);
                Fields.I.value = eval(comp.Laser1Field.Value);
                ud.selectN = 0;
                ud.xVar = Fields.B;
                ud.Nmax = comp.NmaxEditField.Value;
                ud.hamOpts.useRigidRotor = 1;
                ud.hamOpts.useSpinSpinScalar = 1;
                ud.hamOpts.useSpinSpinTensor = 1;
                ud.hamOpts.useSpinRotation = 1;
                ud.hamOpts.useNuclearElectric = 1;
                comp.UserData = ud;
                comp.Fields = Fields;

                comp.Molecule = KRb(40,87);
                updateHamiltonian(comp);
                calculateSpectrum(comp);
                update(comp);
                % collisionE = 0.1; %MHz
                updatePlot(comp);  
                comp.UserData.currPlotXlim = comp.UIAxes.XLim;
                comp.UserData.currPlotYlim = comp.UIAxes.YLim;
                % FieldValueChanged(comp); %gets field values and updates plot
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(comp, event)
            zoomed = checkZoomed(comp);
            if zoomed
                return
            end
            ud = comp.UserData;
            ax = comp.UIAxes;
            delete(findobj(ax,'Tag','update'))

            cStates = ud.currStates;
            cSpec = ud.yVar1.value(:,ud.selectStates);

            %snap to nearest point and highlight
            [~,xIdx] = min(abs(event.IntersectionPoint(1)-ud.xVar.value));
            [~,yIdx] = min(abs(event.IntersectionPoint(2)-cSpec(xIdx,:)));
            ax.NextPlot ="add";
            xline(ax,ud.xVar.value(xIdx),'--',color="k", Tag="update")
            yline(ax,cSpec(xIdx,yIdx),'--',color="k",Tag="update")
            scatter(ax,ud.xVar.value(xIdx), cSpec(xIdx,yIdx),50,'green','filled','o', MarkerFaceAlpha=0.5, Tag="update")
            
            %retrieve state composition of point and update table
            stateComp = round(squeeze(cStates(xIdx,:,yIdx)).^2,6);
            [statesCompTable, ~] = sortrows([array2table(stateComp',"VariableNames","Comp") , ud.statesUCBasis], "Comp","descend");
            comp.UITable.Data = statesCompTable(1:20,:);

            %find diabat and adiabat and highlight 
            if comp.PlotAdiabatsCheckBox.Value
                x = ud.xVar.value;
                if any(strcmp(ud.yVar1.name, ["µ","d"]))
                adiabatLine = plot(ax,x, cSpec(:,yIdx), '-', Color="red", LineWidth=1.5, ...
                    DisplayName="Diabat",Tag="update");   
                legend(ax,[adiabatLine], Location="northeast");                
                else
                [diabat, ~, adiabat, ~] = findAdiabat(x,cSpec,cStates, xIdx,yIdx);
                diabatLine = plot(ax,x, diabat, '-', Color="red", LineWidth=1.7, ...
                    DisplayName="Diabat",Tag="update");
                adiabatLine = plot(ax,x, adiabat, '-', Color="blue",LineWidth=1.2, ...
                    DisplayName="Adiabat",Tag="update");
                legend(ax,[adiabatLine,diabatLine], Location="northeast");
                end
            end
        end

        % Value changed function: BField, EField, Laser1Field, 
        % ...and 3 other components
        function FieldValueChanged(comp, event)
            update(comp);
            if comp.UserData.updateSuccess&&comp.UserData.autoUpdate
                calculateSpectrum(comp);
                updatePlot(comp);            
            end
        end

        % Value changed function: BDirField, EDirField, Laser1DirField
        function FieldDirectionChanged(comp, event)
            Fields = comp.Fields;
            Bdir = eval(comp.BDirField.Value);
            Edir = eval(comp.EDirField.Value);
            Idir = eval(comp.Laser1DirField.Value);
            assert(length(Bdir)==3 && sum(Bdir)~=0);
            assert(length(Edir)==3 && sum(Edir)~=0);
            assert(length(Idir)==3 && sum(Idir)~=0);

            Fields.B.dir=Bdir/norm(Bdir); 
            Fields.E.dir=Edir/norm(Edir); 
            Fields.I.dir=Idir/norm(Idir); 
            
            comp.Fields = Fields;
            updateHamiltonian(comp);  %maybe not the most efficient, but ok
            if comp.UserData.autoUpdate
            calculateSpectrum(comp);
            updatePlot(comp);   
            end
        end

        % Value changed function: ShowPointsCheckBox
        function updatePlotCallback(comp, event)
            updatePlot(comp);   
        end

        % Value changed function: MoleculeEditField
        function MoleculeChanged(comp, event)
            value = comp.MoleculeEditField.Value;
            try
                molecule = eval(value);
            catch
                error(sprintf("%s is not a valid Molecule Class", value));
            end
            comp.Molecule = molecule;
            
            updateHamiltonian(comp);
            if comp.UserData.autoUpdate
            calculateSpectrum(comp);
            updatePlot(comp); 
            end
        end

        % Value changed function: N1EditField, Y1DropDown, Y2DropDown
        function YAxisChanged(comp, event)
            update(comp);
            updatePlot(comp);   
        end

        % Callback function
        function BasisChanged(comp, event)
            
        end

        % Value changed function: NmaxEditField
        function NmaxChanged(comp, event)
            Nmax = comp.NmaxEditField.Value;
            comp.UserData.Nmax = Nmax;
            assert(max(comp.UserData.selectN)<= Nmax);

            % update(comp);
            updateHamiltonian(comp);
            if comp.UserData.autoUpdate
                calculateSpectrum(comp);
                updatePlot(comp);
            end
        end

        % Callback function: Tree
        function HyperfineChanged(comp, event)
            hOpts.useRigidRotor = 0;
            hOpts.useSpinSpinScalar = 0;
            hOpts.useSpinSpinTensor = 0;
            hOpts.useSpinRotation = 0;
            hOpts.useNuclearElectric = 0;
            n = length(comp.Tree.CheckedNodes);
            for k = 1:n
                t = comp.Tree.CheckedNodes(k);
                switch string(t.Text)
                    case "Rigid Rotor"
                        hOpts.useRigidRotor =1;
                    case "Spin-Spin (Scalar)"
                        hOpts.useSpinSpinScalar =1;
                    case "Spin-Spin (Tensor)"
                        hOpts.useSpinSpinTensor =1;
                    case "Spin-Rotation"
                        hOpts.useSpinRotation =1;
                    case "Nuclear Electric"
                        hOpts.useNuclearElectric =1;
                    otherwise
                end
            end
            comp.UserData.hamOpts = hOpts;
            
            if comp.UserData.autoUpdate
            calculateSpectrum(comp);
            updatePlot(comp); 
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(comp, event)
            calculateSpectrum(comp);
            updatePlot(comp); 
        end

        % Value changed function: AutoUpdateButton
        function AutoUpdateButtonValueChanged(comp, event)
            value = comp.AutoUpdateButton.Value;
            comp.UserData.autoUpdate = value;
            if value %turn off manual calculate button
                comp.CalculateButton.Enable = "off";
            else
                comp.CalculateButton.Enable = "on";
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
           comp.UserData.updateSuccess = 0;
           ud = comp.UserData;
           Fields = comp.Fields;
           C=Constants;

            %update xVar -- Has to be first!
           try 
                Fields.B.value = eval(comp.BField.Value);
                Fields.E.value = eval(comp.EField.Value);
                Fields.I.value = eval(comp.Laser1Field.Value);
                Fields.I.polAngle = eval(comp.Laser1PolAngleField.Value);
                Fields.I.polPhase= eval(comp.Laser1PolPhaseField.Value);
                a = Fields.I.polPhase; b = Fields.I.polAngle;
                Fields.I.pol = eval(comp.Laser1PolField.Value);
                if length(Fields.B.value)>1
                    ud.xVar = Fields.B;
                elseif length(Fields.E.value)>1
                    ud.xVar = Fields.E;
                elseif length(Fields.I.value)>1
                    ud.xVar = Fields.I;
                elseif length(Fields.I.polPhase)>1
                    ud.xVar.value = a;
                    ud.xVar.name = "Pol. Phase";
                    ud.xVar.unit = "(°)";
                elseif length(Fields.I.polAngle)>1
                    ud.xVar.value = b;
                    ud.xVar.name = "Pol. Angle";
                    ud.xVar.unit = "(°)";
                    Fields.I.pol = eval(comp.Laser1PolField.Value);
                else
                    return
                end
            catch
                error("Not a valid entry for fields!")
                return
            end
            NPoints = length(Fields.B.value)*length(Fields.E.value)*length(Fields.I.value);
            if NPoints > 302
                warning(sprintf("Do you really want to calculate %d points?",NPoints))
                return
            end
            ud.Nmax = comp.NmaxEditField.Value;
            comp.Fields = Fields;

            %update N Manifold to plot
           selectN = int32(eval(comp.N1EditField.Value));
           assert(max(selectN)<=ud.Nmax);
           if any(ud.selectN ~= selectN)
           ud.selectN = selectN;
           ud.selectStates = ismember(ud.statesUCBasis.N,selectN);
           end

           comp.UserData = ud;
           updateY(comp);
            
           % -------- Add extra stuff if needed ---------%

            comp.UserData.updateSuccess = 1;
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 1146 741];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.UIAxes = uiaxes(comp.GridLayout);
            xlabel(comp.UIAxes, 'X')
            ylabel(comp.UIAxes, 'E (MHz)')
            zlabel(comp.UIAxes, 'Z')
            comp.UIAxes.ButtonDownFcn = matlab.apps.createCallbackFcn(comp, @UIAxesButtonDown, true);
            comp.UITable = uitable(comp.GridLayout);
            comp.UITable.ColumnName = {'Comp'; 'N'; 'mN'; 'mi1'; 'mi2'};
            comp.UITable.RowName = {};
            comp.UITable.SelectionType = 'row';
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.TabGroup = uitabgroup(comp.GridLayout2);
            comp.FieldsTab = uitab(comp.TabGroup);
            comp.FieldsTab.Title = 'Fields';
            comp.GridLayout4 = uigridlayout(comp.FieldsTab);
            comp.Laser1Field = uieditfield(comp.GridLayout4, 'text');
            comp.Laser1Field.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @FieldValueChanged, true);
            comp.Laser1Field.Tooltip = {'Intensity (W/cm^2)'};
            comp.Laser1Field.Value = '0';
            comp.EField = uieditfield(comp.GridLayout4, 'text');
            comp.EField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @FieldValueChanged, true);
            comp.EField.Tooltip = {'Electric Field (V/m)'};
            comp.EField.Value = '0';
            comp.EDirField = uieditfield(comp.GridLayout4, 'text');
            comp.EDirField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @FieldDirectionChanged, true);
            comp.EDirField.Tooltip = {'Electric Field Direction'};
            comp.EDirField.Value = '[0,1,0]';
            comp.Laser1DirField = uieditfield(comp.GridLayout4, 'text');
            comp.Laser1DirField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @FieldDirectionChanged, true);
            comp.Laser1DirField.Tooltip = {'Light Field Direction'};
            comp.Laser1DirField.Value = '[1,0,0]';
            comp.ELabel = uilabel(comp.GridLayout4);
            comp.ELabel.Text = 'E';
            comp.ILabel = uilabel(comp.GridLayout4);
            comp.ILabel.Text = 'I';
            comp.degreeLabel = uilabel(comp.GridLayout4);
            comp.degreeLabel.Text = 'b';
            comp.Laser1PolAngleField = uieditfield(comp.GridLayout4, 'text');
            comp.Laser1PolAngleField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @FieldValueChanged, true);
            comp.Laser1PolAngleField.Tooltip = {'Polarisation Angle (rad)'};
            comp.Laser1PolAngleField.Value = '0';
            comp.aEditFieldLabel = uilabel(comp.GridLayout4);
            comp.aEditFieldLabel.Text = 'a';
            comp.Laser1PolPhaseField = uieditfield(comp.GridLayout4, 'text');
            comp.Laser1PolPhaseField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @FieldValueChanged, true);
            comp.Laser1PolPhaseField.Tooltip = {'Ellipticity (rad)'};
            comp.Laser1PolPhaseField.Value = '0';
            comp.BDirField = uieditfield(comp.GridLayout4, 'text');
            comp.BDirField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @FieldDirectionChanged, true);
            comp.BDirField.Editable = 'off';
            comp.BDirField.Tooltip = {'Magnetic Field Direction'};
            comp.BDirField.Value = '[0,0,1]';
            comp.BField = uieditfield(comp.GridLayout4, 'text');
            comp.BField.CharacterLimits = [1 Inf];
            comp.BField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @FieldValueChanged, true);
            comp.BField.Tooltip = {'Magnetic Field (G)'};
            comp.BField.Value = '[0.5,1:1:50]';
            comp.BLabel = uilabel(comp.GridLayout4);
            comp.BLabel.Text = 'B';
            comp.Laser1PolField = uieditfield(comp.GridLayout4, 'text');
            comp.Laser1PolField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @FieldValueChanged, true);
            comp.Laser1PolField.Tooltip = {'Polarisation Vector. Should be perpendicular to light field.'};
            comp.Laser1PolField.Value = '[0, sin(b)*exp(1i*a), cos(b)]';
            comp.FieldAmplitudeLabel = uilabel(comp.GridLayout4);
            comp.FieldAmplitudeLabel.Text = 'Field Amplitude';
            comp.DirectionLabel_2 = uilabel(comp.GridLayout4);
            comp.DirectionLabel_2.Text = 'Direction';
            comp.PolarisationLabel = uilabel(comp.GridLayout4);
            comp.PolarisationLabel.Text = 'Polarisation';
            comp.MoleculeTab = uitab(comp.TabGroup);
            comp.MoleculeTab.Title = 'Molecule';
            comp.GridLayout7 = uigridlayout(comp.MoleculeTab);
            comp.Tree = uitree(comp.GridLayout7, 'checkbox');
            comp.Tree.Tooltip = {'Terms of the hyperfine interaction to include'};
            comp.RigidRotorNode = uitreenode(comp.Tree);
            comp.RigidRotorNode.Text = 'Rigid Rotor';
            comp.SpinSpinScalarNode = uitreenode(comp.Tree);
            comp.SpinSpinScalarNode.Text = 'Spin-Spin (Scalar)';
            comp.SpinSpinTensorNode = uitreenode(comp.Tree);
            comp.SpinSpinTensorNode.Text = 'Spin-Spin (Tensor)';
            comp.SpinRotationNode = uitreenode(comp.Tree);
            comp.SpinRotationNode.Text = 'Spin-Rotation';
            comp.NuclearElectricNode = uitreenode(comp.Tree);
            comp.NuclearElectricNode.Text = 'Nuclear Electric';
            % Assign Checked Nodes
            comp.Tree.CheckedNodes = [comp.RigidRotorNode, comp.SpinSpinScalarNode, comp.SpinSpinTensorNode, comp.SpinRotationNode, comp.NuclearElectricNode];
            % Assign Checked Nodes
            comp.Tree.CheckedNodesChangedFcn = matlab.apps.createCallbackFcn(comp, @HyperfineChanged, true);
            comp.MoleculeLabel = uilabel(comp.GridLayout7);
            comp.MoleculeLabel.Text = 'Molecule:';
            comp.MoleculeEditField = uieditfield(comp.GridLayout7, 'text');
            comp.MoleculeEditField.CharacterLimits = [0 15];
            comp.MoleculeEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @MoleculeChanged, true);
            comp.MoleculeEditField.Tooltip = {'Supported Molecules:'; 'KRb,'; 'NaCs,'; 'NaK,'; 'NaRb,'; 'RbCs.'; 'See Molecule classes for supported and default isotopes.'};
            comp.MoleculeEditField.Placeholder = 'Molecule';
            comp.MoleculeEditField.Value = 'KRb(40,87)';
            comp.BasisTab = uitab(comp.TabGroup);
            comp.BasisTab.Title = 'Basis';
            comp.GridLayout6 = uigridlayout(comp.BasisTab);
            comp.TextArea_3 = uitextarea(comp.GridLayout6);
            comp.TextArea_3.Editable = 'off';
            comp.TextArea_3.Enable = 'off';
            comp.TextArea_3.Visible = 'off';
            comp.NmaxEditFieldLabel = uilabel(comp.GridLayout6);
            comp.NmaxEditFieldLabel.Text = 'Nmax';
            comp.NmaxEditField = uieditfield(comp.GridLayout6, 'numeric');
            comp.NmaxEditField.Limits = [0 5];
            comp.NmaxEditField.RoundFractionalValues = 'on';
            comp.NmaxEditField.ValueDisplayFormat = '%.0f';
            comp.NmaxEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @NmaxChanged, true);
            comp.NmaxEditField.Tooltip = {'Maximum Number of rotational states to include in calculation. N>4 will take significant time to calculate. Currently limited to N=5 by design.'};
            comp.NmaxEditField.Value = 2;
            comp.BasisDropDownLabel = uilabel(comp.GridLayout6);
            comp.BasisDropDownLabel.Enable = 'off';
            comp.BasisDropDownLabel.Text = 'Basis';
            comp.BasisDropDown = uidropdown(comp.GridLayout6);
            comp.BasisDropDown.Items = {'Uncoupled', 'Spin-Coupled', 'Fully-Coupled'};
            comp.BasisDropDown.Enable = 'off';
            comp.BasisDropDown.Value = 'Uncoupled';
            comp.PlotTab = uitab(comp.TabGroup);
            comp.PlotTab.Title = 'Plot';
            comp.GridLayout5 = uigridlayout(comp.PlotTab);
            comp.PlotAdiabatsCheckBox = uicheckbox(comp.GridLayout5);
            comp.PlotAdiabatsCheckBox.Text = 'Plot Adiabats';
            comp.ShowPointsCheckBox = uicheckbox(comp.GridLayout5);
            comp.ShowPointsCheckBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updatePlotCallback, true);
            comp.ShowPointsCheckBox.Text = 'Show Points';
            comp.Y1DropDownLabel = uilabel(comp.GridLayout5);
            comp.Y1DropDownLabel.Text = 'Y1';
            comp.Y1DropDown = uidropdown(comp.GridLayout5);
            comp.Y1DropDown.Items = {'E', 'E - <E>', 'E - a0', 'µ', 'd'};
            comp.Y1DropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @YAxisChanged, true);
            comp.Y1DropDown.Value = 'E';
            comp.Y2DropDownLabel = uilabel(comp.GridLayout5);
            comp.Y2DropDownLabel.Enable = 'off';
            comp.Y2DropDownLabel.Visible = 'off';
            comp.Y2DropDownLabel.Text = 'Y2';
            comp.Y2DropDown = uidropdown(comp.GridLayout5);
            comp.Y2DropDown.Items = {'E', 'E - <E>', 'µ', 'd'};
            comp.Y2DropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @YAxisChanged, true);
            comp.Y2DropDown.Enable = 'off';
            comp.Y2DropDown.Visible = 'off';
            comp.Y2DropDown.Value = 'E';
            comp.N1EditFieldLabel = uilabel(comp.GridLayout5);
            comp.N1EditFieldLabel.Text = 'N1';
            comp.N1EditField = uieditfield(comp.GridLayout5, 'text');
            comp.N1EditField.CharacterLimits = [1 10];
            comp.N1EditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @YAxisChanged, true);
            comp.N1EditField.Tooltip = {'Rotational state manifold to plot. Must be smaller than Nmax.'};
            comp.N1EditField.Value = '0';
            comp.N2EditFieldLabel = uilabel(comp.GridLayout5);
            comp.N2EditFieldLabel.Enable = 'off';
            comp.N2EditFieldLabel.Text = 'N2';
            comp.N2EditField = uieditfield(comp.GridLayout5, 'text');
            comp.N2EditField.Enable = 'off';
            comp.GridLayout3 = uigridlayout(comp.GridLayout2);
            comp.CalculateButton = uibutton(comp.GridLayout3, 'push');
            comp.CalculateButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @CalculateButtonPushed, true);
            comp.CalculateButton.Enable = 'off';
            comp.CalculateButton.Text = 'Calculate';
            comp.AutoUpdateButton = uibutton(comp.GridLayout3, 'state');
            comp.AutoUpdateButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @AutoUpdateButtonValueChanged, true);
            comp.AutoUpdateButton.Text = 'AutoUpdate';
            comp.AutoUpdateButton.Value = true;
            comp.Label = uilabel(comp.GridLayout3);
            comp.Label.Text = '';
            comp.statusLamp = uilamp(comp.GridLayout3);
            comp.statusLabel = uilabel(comp.GridLayout3);
            comp.statusLabel.Text = '';
            comp.ExportButton = uibutton(comp.GridLayout3, 'push');
            comp.ExportButton.Enable = 'off';
            comp.ExportButton.Tooltip = {'Coming Soon'};
            comp.ExportButton.Text = 'Export';
            % Execute the startup function
            initialise(comp)
        end
    end
end